classdef DrapeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExportSteeringCurveButton       matlab.ui.control.Button
        StateLamp                       matlab.ui.control.Lamp
        ReadyLampLabel                  matlab.ui.control.Label
        KinDrapeMAXsinglecourseappLabel  matlab.ui.control.Label
        PlotmoldButton                  matlab.ui.control.Button
        AxisequalButton                 matlab.ui.control.StateButton
        DtopviewButton                  matlab.ui.control.Button
        Restore3DviewButton             matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        InputdataTab                    matlab.ui.container.Tab
        SavetonewinpButton              matlab.ui.control.Button
        DrapingcontrolPanel             matlab.ui.container.Panel
        DelcolButton2                   matlab.ui.control.Button
        AddcolButton2                   matlab.ui.control.Button
        DelcolButton1                   matlab.ui.control.Button
        AddcolButton1                   matlab.ui.control.Button
        LongitudinalptrefDropDown       matlab.ui.control.DropDown
        LongitudinalptrefDropDownLabel  matlab.ui.control.Label
        SteerPts1                       matlab.ui.control.Table
        LongitudinalButtonGroup         matlab.ui.container.ButtonGroup
        GeodesicButton_long             matlab.ui.control.ToggleButton
        SteeringButton_long             matlab.ui.control.ToggleButton
        TransverseptrefDropDown         matlab.ui.control.DropDown
        TransverseptrefDropDownLabel    matlab.ui.control.Label
        SteerPts2                       matlab.ui.control.Table
        TransverseButtonGroup           matlab.ui.container.ButtonGroup
        GeodesicButton_trans            matlab.ui.control.ToggleButton
        SteeringButton_trans            matlab.ui.control.ToggleButton
        InputparametersPanel            matlab.ui.container.Panel
        SizeSpinner                     matlab.ui.control.Spinner
        SizeSpinnerLabel                matlab.ui.control.Label
        SizeSpinner_2                   matlab.ui.control.Spinner
        OrgNodePctSpinner               matlab.ui.control.Spinner
        OrgNodePctSpinnerLabel          matlab.ui.control.Label
        PreShearSpinner                 matlab.ui.control.Spinner
        PreShearSpinnerLabel            matlab.ui.control.Label
        AngSpinner                      matlab.ui.control.Spinner
        AngSpinnerLabel                 matlab.ui.control.Label
        OrgNodeSpinner                  matlab.ui.control.Spinner
        OrgNodeSpinnerLabel             matlab.ui.control.Label
        OrgNodeSpinner_2                matlab.ui.control.Spinner
        OrgSpinner                      matlab.ui.control.Spinner
        OrgSpinnerLabel                 matlab.ui.control.Label
        OrgSpinner_2                    matlab.ui.control.Spinner
        GridSpinner                     matlab.ui.control.Spinner
        GridSpinnerLabel                matlab.ui.control.Label
        GridSpinner_2                   matlab.ui.control.Spinner
        dSpinner                        matlab.ui.control.Spinner
        dSpinnerLabel                   matlab.ui.control.Label
        OrgNodePctSpinner_2             matlab.ui.control.Spinner
        DisplayproblemdataPanel         matlab.ui.container.Panel
        MinabsshearangEditField         matlab.ui.control.EditField
        MinabsshearangEditFieldLabel    matlab.ui.control.Label
        EllapsedtimeEditField           matlab.ui.control.EditField
        EllapsedtimeEditFieldLabel      matlab.ui.control.Label
        FabricsizeEditField             matlab.ui.control.EditField
        FabricsizeEditFieldLabel        matlab.ui.control.Label
        NumberofcellsEditField          matlab.ui.control.NumericEditField
        NumberofcellsEditFieldLabel     matlab.ui.control.Label
        ReloadButton                    matlab.ui.control.Button
        InputfileDropDown               matlab.ui.control.DropDown
        InputfileLabel                  matlab.ui.control.Label
        MoldDropDown                    matlab.ui.control.DropDown
        MoldDropDownLabel               matlab.ui.control.Label
        PlotsettingsTab                 matlab.ui.container.Tab
        PlycontourcolorDropDown         matlab.ui.control.DropDown
        PlycontourcolorDropDownLabel    matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        PlyplotzoffsetSpinner           matlab.ui.control.Spinner
        PlyplotzoffsetSpinnerLabel      matlab.ui.control.Label
        DisplaylegendCheckBox           matlab.ui.control.CheckBox
        CalibrateotherscreenButton      matlab.ui.control.Button
        PlotmoldmeshboundaryCheckBox    matlab.ui.control.CheckBox
        FiguresizeDropDown              matlab.ui.control.DropDown
        FiguresizeDropDownLabel         matlab.ui.control.Label
        PlotgeneratorcurvesCheckBox     matlab.ui.control.CheckBox
        PlotmoldedgesCheckBox           matlab.ui.control.CheckBox
        PlotmoldtrimeshCheckBox         matlab.ui.control.CheckBox
        ColormapButtonGroup             matlab.ui.container.ButtonGroup
        AbaqusshearlimitSpinner         matlab.ui.control.Spinner
        AbaqusshearlimitSpinnerLabel    matlab.ui.control.Label
        AbaqusButton                    matlab.ui.control.RadioButton
        JetButton                       matlab.ui.control.RadioButton
        UpdateplotButton                matlab.ui.control.Button
        ShearanglesButtonGroup          matlab.ui.container.ButtonGroup
        SignedButton                    matlab.ui.control.RadioButton
        AbsoluteButton                  matlab.ui.control.RadioButton
        SettingsTab                     matlab.ui.container.Tab
        TrimplytonetboundaryCheckBox    matlab.ui.control.CheckBox
        FabricdimensioninputButtonGroup  matlab.ui.container.ButtonGroup
        GridanddButton                  matlab.ui.control.ToggleButton
        SizeanddButton                  matlab.ui.control.ToggleButton
        keyboardafterdrapinganalysisCheckBox  matlab.ui.control.CheckBox
        OrgNodeinputButtonGroup         matlab.ui.container.ButtonGroup
        GriddimpctButton                matlab.ui.control.ToggleButton
        NodeindicesButton               matlab.ui.control.ToggleButton
        UserguideTab                    matlab.ui.container.Tab
        UserGuideText                   matlab.ui.control.TextArea
        RunDrapingAnalysisButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        Inp % Input data struct with inputs
        Set % Settings struct with inputs
        Plt % Plot settings with inputs
        Dra % Draping data
        Mold % Mold data
        
        d
        Org
        OrgNode
        OrgNodePct
        Ang
        PreShear
        F
        DT
        z
        Grid
        Size
        SteerPts
        SteerPtsRef
        DraContr
        
        ColModIdx1
        ColModIdx2
        
        AxHan

        Node_trim
    end
    
    methods (Access = private)
        
        function app = ApplyGUIValues(app)
            
            app.Inp.MoldName = app.MoldDropDown.Value;
            app.Inp.FileName = app.InputfileDropDown.Value;
            
            app.Set.TrimPlyToNetBd = app.TrimplytonetboundaryCheckBox.Value;
            app.Set.CheckAspect = false;
            app.Set.AspectLim = 20;
            
            app.Plt.AbsShearAng = app.AbsoluteButton.Value;
            app.Plt.AbaqusColor = app.AbaqusButton.Value;
            app.Plt.ShearLim = app.AbaqusshearlimitSpinner.Value;
            app.Plt.TriMesh = app.PlotmoldtrimeshCheckBox.Value;
            app.Plt.MoldNetBd = app.PlotmoldedgesCheckBox.Value;
            app.Plt.GenCurve = app.PlotgeneratorcurvesCheckBox.Value;
            app.Plt.MeshBoundary = app.PlotmoldmeshboundaryCheckBox.Value;
            app.Plt.DispLegend = app.DisplaylegendCheckBox.Value;
            app.Plt.GeoInc = false;
            app.Plt.FigWindowSize = app.FiguresizeDropDown.Value;
            app.Plt.zShift = 1e-3*app.PlyplotzoffsetSpinner.Value;
            app.Plt.PlyPlt = {app.PlycontourcolorDropDown.Value};
            
        end
        
        function app = GetInputFile(app)
            [~,app.Inp] = ReadInputFiles(app.Inp,'Parse','course');
            
            % Extract fields from struct
            app.d = app.Inp.d;
            app.Grid = app.Inp.Grid;
            app.Size = app.Inp.Size;
            app.Org = app.Inp.Org;
            app.Ang = app.Inp.Ang;
            app.OrgNode = app.Inp.OrgNode;
            app.OrgNodePct = app.Inp.OrgNodePct;
            app.SteerPts = app.Inp.SteerPts;
            app.PreShear = app.Inp.PreShear;
            app.DraContr = app.Inp.DraContr;
            app.SteerPtsRef = app.Inp.SteerPtsRef;
        end
        
        function app = GetCurrentInputFileNames(app)
            % Read input files defined for current mold and update
            % options in Inputfile dropdown in GUI. Pick 1st as the current
            app.Inp.FileList = ReadInputFiles(app.Inp,'Read','course');
            if ~isempty(app.Inp.FileList)
                app.Inp.FileName = app.Inp.FileList{1};
                app.InputfileDropDown.Items = app.Inp.FileList;
                app.InputfileDropDown.Value = app.Inp.FileList{1};
            else
                app.Inp.FileName = [];
                app.InputfileDropDown.Items = {};
                app.InputfileDropDown.Value = {};
            end
        end
        
        function BusyLamp(app,status)
            % This function sets state lamp label and color based on the variable status
            % 1: Busy, red
            % 0: Ready, green
            if status == 1
                app.StateLamp.Color = [1 0 0];
                app.ReadyLampLabel.Text = 'Busy';
                pause(1e-6)
            else
                app.StateLamp.Color = [0 1 0];
                app.ReadyLampLabel.Text = 'Ready';
            end
        end
        
        function ComputeProblemData(app)
            % Compute fabric size and number of cells and display
            FabricSizeStr = [num2str((app.Grid(1)-1)*app.d,'%.2f') ' x ' num2str((app.Grid(2)-1)*app.d,'%.2f')];
            app.FabricsizeEditField.Value = FabricSizeStr;
            app.NumberofcellsEditField.Value = (max(0,app.Grid(1)-1))*max(0,(app.Grid(2)-1));
        end
    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            close all
            
            % Move window to the center
            movegui(app.UIFigure,'center')
            
            [CurrFolder,~,~] = fileparts([mfilename('fullpath') '.mlapp']);
            cd(CurrFolder);

            %cd('C:\ck_svn\Kinematic Draping\Main program');

            if ~isdeployed
                addpath('./Functions/')

                % Check if temporary folder exist
                if ~isfolder('./Temporary files/')
                    mkdir('./Temporary files/')
                end
            else
                if ~isfolder('C:/Drape_Output/Temporary files/')
                    mkdir('C:/Drape_Output/Temporary files/')
                end
            end

            BusyLamp(app,1)
            
            app.Inp.MoldName = 'Hemisphere';
            app.Inp.FileName = 'C_Northpole.m';
                        
            % Read input files defined for current mold and update
            % options in Inputfile dropdown in GUI
            app.Inp.FileList = ReadInputFiles(app.Inp,'Read','course');
            app.InputfileDropDown.Items = app.Inp.FileList;
            
            app.MoldDropDown.Value = 'Hemisphere';
            app.InputfileDropDown.Value = 'C_Northpole.m';
            
            % Hard code some settings that are not available in the GUI
            app.Set.MeshOrCurveWarning = true;
            app.Set.ExtrapSteerCurves = true;
            app.Set.CheckAspect = false;
            app.Set.Mode = 'analysis';
            
            % Get mold and input file
            app.Inp.MoldLayerNo = 1;
            [app.Mold,app.F,app.DT,app.z] = ReadMoldData(app.Inp,app.Set);
            % Go back to root folder
            cd('../../')

            % Update GUI values
            UpdateGUIIValues(app)
            
            % Display the User Guide
            app.UserGuideText.Value = fileread('DrapeAppUserGuide.txt');
            
            BusyLamp(app,0)
        end

        % Button pushed function: RunDrapingAnalysisButton
        function RunDrapingAnalysis(app, event)
            BusyLamp(app,1)
            
            clc
            close all
            
            % Apply settings
            app = ApplyGUIValues(app);
            
            tic
            
            %% Draping analysis
            % No support for root course offset
            RootCourseOffset = [];

            % Step 1: Generators (geodesic from unfolded tri or steering curve)
            app.Dra = Step1(app.d,app.Grid,app.Ang,app.Org,app.OrgNode,app.PreShear,app.F,app.DT,app.z,app.DraContr,...
                app.SteerPts,app.SteerPtsRef,app.Mold,app.Plt,app.Set,RootCourseOffset);
            
            % Step 2: Constrained nodes
            app.Dra = Step2(app.Dra,app.Grid,app.d,app.OrgNode,app.F);
            
            % Trim ply to mold boundary
            if app.Set.TrimPlyToNetBd
                [app.Dra, app.Node_trim] = TrimPlyToBoundary(app.Mold,app.Dra,app.OrgNode,app.Grid,app.F,app.d);
            else
                app.Dra.TrimArea = 0;
                app.Node_trim = app.Dra.Node;
            end

            %% Plot
            [~,~,app.AxHan] = DrapePlot(app.Plt,app.Set,app.Dra,app.Mold,app.F,app.DT,app.z,app.Dra.OrgAdj);
            
            AxisequalButtonValueChanged(app, event)
            
            % Stop timer and display value
            EllapsedTime = toc;
            app.EllapsedtimeEditField.Value = [num2str(EllapsedTime,'%.2f') ' s'];
            
            % Display min abs shear angle
            app.MinabsshearangEditField.Value = [num2str(min(min(abs(app.Dra.P(:,:,4)))),'%.2G') sprintf('%c', char(176))];
            
            % Enable plot buttons and steering curve export
            app.UpdateplotButton.Enable = 'on';
            app.Restore3DviewButton.Enable = 'on';
            app.DtopviewButton.Enable = 'on';
            app.AxisequalButton.Enable = 'on';
            app.ExportSteeringCurveButton.Enable = 'on';
            
            % Make data available in the workspace
            assignin('base','Node',app.Dra.Node);
            assignin('base','P',app.Dra.P);
            
            if app.keyboardafterdrapinganalysisCheckBox.Value
                keyboard
            end
            
            BusyLamp(app,0)
        end

        % Value changed function: MoldDropDown
        function MoldDropDownValueChanged(app, event)
            BusyLamp(app,1)
            
            % Apply new mold and preform settings settings
            app.Inp.MoldName = app.MoldDropDown.Value;
            
            app = GetCurrentInputFileNames(app);
            
            % Get mold
            app.Inp(1).MoldLayerNo = 1;

            [app.Mold,app.F,app.DT,app.z] = ReadMoldData(app.Inp,app.Set);
            % Go back to root folder
            cd('../../')
            
            % Enable / disable the options of steering curves relative to
            % mold edges, if the mold edges are defined for the current
            % mold
            if ~isempty(app.Mold.MoldEdge)
                % Use 'Abs' and the relative options
                app.LongitudinalptrefDropDown.Items = {'Left', 'Right', 'Abs'};
                app.TransverseptrefDropDown.Items = {'Bottom', 'Abs'};
            else
                % Only use 'Abs'
                app.LongitudinalptrefDropDown.Items = {'Abs'};
                app.TransverseptrefDropDown.Items = {'Abs'};
            end
            
            % Get input file and update GUI values
            UpdateGUIIValues(app, event)
                        
            BusyLamp(app,0)
            
            if isempty(app.Mold.X)
                startupFcn(app)
            end
        end

        % Button pushed function: UpdateplotButton
        function UpdatePlot(app, event)
            BusyLamp(app,1)
            
            if ~isempty(app.AxHan)
                %% Apply new settings
                app = ApplyGUIValues(app);
                
                % Retrieve current view
                [caz,cel] = view(app.AxHan);
                
                close all
                
                %% Plot
                [~,~,app.AxHan] = DrapePlot(app.Plt,app.Set,app.Dra,app.Mold,app.F,app.DT,app.z,app.Dra.OrgAdj);
                
                % Set previous view
                view(caz,cel)
                
                AxisequalButtonValueChanged(app, event)
            end
            
            BusyLamp(app,0)
        end

        % Button pushed function: Restore3DviewButton
        function Restore3DviewButtonPushed(app, event)
            BusyLamp(app,1)
            view(3)
            BusyLamp(app,0)
        end

        % Button pushed function: DtopviewButton
        function DtopviewButtonPushed(app, event)
            BusyLamp(app,1)
            view(2)
            BusyLamp(app,0)
        end

        % Callback function
        function UpdateGUIIValues(app, event)
            % Get input file
            app = GetInputFile(app);
            
            % Update the GUI values
            app.dSpinner.Value = app.d;
            app.GridSpinner.Value = app.Grid(1);
            app.GridSpinner_2.Value = app.Grid(2);
            app.SizeSpinner.Value = app.Size(1);
            app.SizeSpinner_2.Value = app.Size(2);
            app.OrgSpinner.Value = app.Org(1);
            app.OrgSpinner_2.Value = app.Org(2);
            app.AngSpinner.Value = app.Ang;
            app.OrgNodeSpinner.Value = app.OrgNode(1);
            app.OrgNodeSpinner_2.Value = app.OrgNode(2);
            app.OrgNodePctSpinner.Value = app.OrgNodePct(1);
            app.OrgNodePctSpinner_2.Value = app.OrgNodePct(2);
            app.PreShearSpinner.Value = app.PreShear;
            
            % Set longitudinal draping control buttons
            if strcmp(app.DraContr{1},'Geo')
                app.GeodesicButton_long.Value = true;
                % Disable table with steering points
                app.LongitudinalptrefDropDown.Enable = 'off';
                app.SteerPts1.Enable = 'off';
                app.SteerPts1.Data = array2table([]);
                
                % Disable add/delete col buttons
                app.AddcolButton1.Enable = false;
                app.DelcolButton1.Enable = false;
            else
                app.GeodesicButton_long.Value = false;
                % Enable table and display steering points
                app.SteerPts1.Enable = 'on';
                app.LongitudinalptrefDropDown.Enable = 'on';
                app.SteerPts1.Data = array2table(app.SteerPts{1});
                
                % Enable add/delete col buttons
                app.AddcolButton1.Enable = true;
                app.DelcolButton1.Enable = true;
                app.ColModIdx1 = [1 1];
            end
            
            % Set transverse draping control buttons
            if strcmp(app.DraContr{2},'Geo')
                app.GeodesicButton_trans.Value = true;
                % Disable table with steering points
                app.SteerPts2.Enable = 'off';
                app.TransverseptrefDropDown.Enable = 'off';
                app.SteerPts2.Data = array2table([]);
                
                % Disable add/delete col buttons
                app.AddcolButton2.Enable = false;
                app.DelcolButton2.Enable = false;
            else
                app.GeodesicButton_trans.Value = false;
                % Enable table and display steering points
                app.SteerPts2.Enable = 'on';
                app.TransverseptrefDropDown.Enable = 'on';
                app.SteerPts2.Data = array2table(app.SteerPts{2});
                
                % Enable add/delete col buttons
                app.AddcolButton2.Enable = true;
                app.DelcolButton2.Enable = true;
                app.ColModIdx2 = [1 1];
            end       

            if app.GeodesicButton_long.Value && app.GeodesicButton_trans.Value 
                % Enable Org and Ang spinners, enable preshear
                app.OrgSpinner.Enable = 'on';
                app.OrgSpinner_2.Enable = 'on';
                app.AngSpinner.Enable = 'on';
                app.PreShearSpinner.Enable = 'on';
            elseif ~app.GeodesicButton_long.Value && app.GeodesicButton_trans.Value
                % Disable Org1 and Ang spinners, enable preshear
                app.OrgSpinner.Enable = 'off';
                app.OrgSpinner_2.Enable = 'on';
                app.AngSpinner.Enable = 'off';
                app.PreShearSpinner.Enable = 'on';
            elseif ~app.GeodesicButton_long.Value && ~app.GeodesicButton_trans.Value
                % Disable Org1, Org2 and Ang spinners + preshear
                app.OrgSpinner.Enable = 'off';
                app.AngSpinner.Enable = 'off';
                app.OrgSpinner_2.Enable = 'off';
                app.PreShearSpinner.Enable = 'off';
            end

            % Set the longitudinal and transverse pts. ref values
            app.LongitudinalptrefDropDown.Value = app.SteerPtsRef{1};
            app.TransverseptrefDropDown.Value = app.SteerPtsRef{2};
            
            % Compute fabric size and number of cells
            ComputeProblemData(app)
        end

        % Callback function: AngSpinner, GridSpinner, GridSpinner_2, 
        % ...and 16 other components
        function UpdateInputFileValuesFromGUI(app, event)
            BusyLamp(app,1)
            
            % Set values
            app.d = app.dSpinner.Value;
            app.Org(1) = app.OrgSpinner.Value;
            app.Org(2) = app.OrgSpinner_2.Value;
            app.Ang = app.AngSpinner.Value;
            app.PreShear = app.PreShearSpinner.Value;
            
            % Convert between Size and Grid
            if app.SizeanddButton.Value
                [app.Grid,app.d,app.Size,L_upd] = CourseDimToGridAndd([app.SizeSpinner.Value app.SizeSpinner_2.Value],app.d,[]);
                app.dSpinner.Value = app.d;
                
                if ~isempty(L_upd)
                    warndlg(['The course length was changed to ' num2str(L_upd) ' to fit the choice of ' ...
                    'd (' num2str(app.d) ') and width (' num2str(app.Size(1)) ')'])
                    uiwait
                end
            elseif app.GridanddButton.Value
                app.Grid(1) = app.GridSpinner.Value;
                app.Grid(2) = app.GridSpinner_2.Value;
                app.Size = (app.Grid-1) .* app.d;
            end
            
            % Convert between OrgNode as the node idx or percentage
            if app.GriddimpctButton.Value
                % Calculate the value of the OrgNode based on the
                % percentage and the Grid 
                [app.OrgNode,app.OrgNodePct] = OrgNodeFromPct([],[app.OrgNodePctSpinner.Value app.OrgNodePctSpinner_2.Value],app.Grid);
            elseif app.NodeindicesButton.Value
                % Use the OrgNode as input in the spinner
                app.OrgNode(1) = app.OrgNodeSpinner.Value;
                app.OrgNode(2) = app.OrgNodeSpinner_2.Value;
            end
            
            % Get the values of longitudinal and transverse ref. pts
            app.SteerPtsRef{1} = app.LongitudinalptrefDropDown.Value;
            app.SteerPtsRef{2} = app.TransverseptrefDropDown.Value;
            
            % Set the input file value to 'Geo' or 'Steer'
            if app.GeodesicButton_long.Value
                app.DraContr{1} = 'Geo';
                % Disable the table with steering points and ref drop down
                app.SteerPts1.Enable = 'off';
                app.LongitudinalptrefDropDown.Enable = 'off';
                
                % Disable add/delete col buttons
                app.AddcolButton1.Enable = false;
                app.DelcolButton1.Enable = false;
            else
                app.DraContr{1} = 'Steer';
                % Enable table with steering points and display the points
                app.LongitudinalptrefDropDown.Enable = 'on';
                app.SteerPts1.Enable = 'on';
                app.SteerPts{1} = table2array(app.SteerPts1.Data);
                % Remove NaN values if cells are empty
                app.SteerPts{1}(:,any(isnan(app.SteerPts{1}),1)) = [];
                
                % Enable add/delete col buttons
                app.AddcolButton1.Enable = true;
                app.DelcolButton1.Enable = true;
                app.ColModIdx1 = [1 1];
            end
            
            if app.GeodesicButton_trans.Value
                app.DraContr{2} = 'Geo';
                % Disable the table with steering points
                app.SteerPts2.Enable = 'off';
                app.TransverseptrefDropDown.Enable = 'off';
                
                % Disable add/delete col buttons
                app.AddcolButton2.Enable = false;
                app.DelcolButton2.Enable = false;
            else
                app.DraContr{2} = 'Steer';
                % Enable table with steering points and display the points
                app.SteerPts2.Enable = 'on';
                app.TransverseptrefDropDown.Enable = 'on';
                app.SteerPts{2} = table2array(app.SteerPts2.Data);
                % Remove NaN values if cells are empty
                app.SteerPts{2}(:,any(isnan(app.SteerPts{2}),1)) = [];
                
                % Enable add/delete col buttons
                app.AddcolButton2.Enable = true;
                app.DelcolButton2.Enable = true;
                app.ColModIdx2 = [1 1];
            end
            
            if app.GeodesicButton_long.Value && app.GeodesicButton_trans.Value 
                % Enable Org and Ang spinners, enable preshear
                app.OrgSpinner.Enable = 'on';
                app.OrgSpinner_2.Enable = 'on';
                app.AngSpinner.Enable = 'on';
                app.PreShearSpinner.Enable = 'on';
            elseif ~app.GeodesicButton_long.Value && app.GeodesicButton_trans.Value
                % Disable Org1 and Ang spinners, enable preshear
                app.OrgSpinner.Enable = 'off';
                app.OrgSpinner_2.Enable = 'on';
                app.AngSpinner.Enable = 'off';
                app.PreShearSpinner.Enable = 'on';
            elseif ~app.GeodesicButton_long.Value && ~app.GeodesicButton_trans.Value
                % Disable Org1, Org2 and Ang spinners + preshear
                app.OrgSpinner.Enable = 'off';
                app.AngSpinner.Enable = 'off';
                app.OrgSpinner_2.Enable = 'off';
                app.PreShearSpinner.Enable = 'off';
            end


            if strcmp(app.DraContr{1},'Geo') && strcmp(app.DraContr{2},'Steer')
                %fprintf(2,['Longitudinal generator from geodesic in combination with \n' ...
                %    'transverse generator from steering curve is not supported\n\n'])
                errordlg(['Longitudinal generator from geodesic in combination with ' ...
                    'transverse generator from steering curve is currently not supported.'])
                uiwait
                app.DraContr{1} = 'Steer';
                app.SteeringButton_long.Value = true;
            end
            
            % Set limits
            %app.OrgNodeSpinner.Limits = [1 app.GridSpinner.Value - 1];
            %app.OrgNodeSpinner_2.Limits = [1 app.GridSpinner_2.Value - 1];
            
            % Compute problem data
            ComputeProblemData(app)
            
            BusyLamp(app,0)
        end

        % Value changed function: AxisequalButton
        function AxisequalButtonValueChanged(app, event)
            BusyLamp(app,1)
            
            if app.AxisequalButton.Value
                axis('equal');
            else
                axis('square');
            end
           
            BusyLamp(app,0)
        end

        % Button pushed function: PlotmoldButton
        function PlotmoldButtonPushed(app, event)
            BusyLamp(app,1)
            
            %% Apply new settings
            app = ApplyGUIValues(app);
            
            close all
            
            app.Dra.OrgAdj = app.Org;
            
            % Get the steering curves
            if strcmp(app.DraContr{1},'Steer') || strcmp(app.DraContr{2},'Steer') 
                [app.Dra.SteerPtsAbs,app.Dra.OrgAdj] = AbsSteeringPts(app.SteerPts,app.DraContr,app.SteerPtsRef,app.Mold,app.Org);
            end
                
            % Delete any draping pattern
            if isstruct(app.Dra) && isfield(app.Dra,'P')
                app.Dra = rmfield(app.Dra,'P');
            end
            app.Plt.GenCurve = false;    
            
            %% Plot
            [~,~,app.AxHan] = DrapePlot(app.Plt,app.Set,app.Dra,app.Mold,app.F,app.DT,app.z,app.Dra.OrgAdj);
            
            % Plot steering pts.
            StyleCell = {'ro','rx'};
            if isfield(app.Dra,'SteerPtsAbs')
                for p = find(~cellfun(@isempty,app.Dra.SteerPtsAbs))
                    scatter3(app.Dra.SteerPtsAbs{p}(1,:),app.Dra.SteerPtsAbs{p}(2,:),app.F(app.Dra.SteerPtsAbs{p}(1,:),app.Dra.SteerPtsAbs{p}(2,:)),StyleCell{p})
                end
            end
            
            % Remove colorbar
            colorbar(app.AxHan,'off');
            
            AxisequalButtonValueChanged(app, event)
            
            % Enable plot buttons
            app.UpdateplotButton.Enable = 'on';
            app.Restore3DviewButton.Enable = 'on';
            app.DtopviewButton.Enable = 'on';
            app.AxisequalButton.Enable = 'on';
            
            BusyLamp(app,0)
        end

        % Callback function: InputfileDropDown, ReloadButton
        function InputfileDropDownValueChanged(app, event)
            BusyLamp(app,1)
            
            app.Inp.FileName = app.InputfileDropDown.Value;
            
            UpdateGUIIValues(app, event)
            
            BusyLamp(app,0)
        end

        % Button pushed function: CalibrateotherscreenButton
        function CalibrateotherscreenButtonPushed(app, event)
            BusyLamp(app,1)
            
            % Run function to define the other screen
            DefineOtherScreen
            
            BusyLamp(app,0)
        end

        % Selection changed function: OrgNodeinputButtonGroup
        function OrgNodeinputButtonGroupSelectionChanged(app, event)
            if app.GriddimpctButton.Value
                % Set visibility of buttons and label
                app.OrgNodeSpinner.Visible = false;
                app.OrgNodeSpinner_2.Visible = false;
                
                app.OrgNodePctSpinner.Visible = true;
                app.OrgNodePctSpinner_2.Visible = true;
                
                app.OrgNodePctSpinnerLabel.Visible = true;
                app.OrgNodeSpinnerLabel.Visible = false;
                
                % Set the values of the spinners
                app.OrgNodePctSpinner.Value = app.OrgNodePct(1);
                app.OrgNodePctSpinner_2.Value = app.OrgNodePct(2);
                
            elseif app.NodeindicesButton.Value
                % Set visibility of buttons and label
                app.OrgNodeSpinner.Visible = true;
                app.OrgNodeSpinner_2.Visible = true;
                
                app.OrgNodePctSpinner.Visible = false;
                app.OrgNodePctSpinner_2.Visible = false;
                
                app.OrgNodePctSpinnerLabel.Visible = false;
                app.OrgNodeSpinnerLabel.Visible = true;
                
                % Set the values of the spinners
                app.OrgNodeSpinner.Value = app.OrgNode(1);
                app.OrgNodeSpinner_2.Value = app.OrgNode(2);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Cell selection callback: SteerPts1
        function SteerPts1CellSelection(app, event)
            app.ColModIdx1 = event.Indices;
        end

        % Cell selection callback: SteerPts2
        function SteerPts2CellSelection(app, event)
            app.ColModIdx2 = event.Indices;
        end

        % Button pushed function: DelcolButton1
        function DelcolButton1Pushed(app, event)
            if app.ColModIdx1(2) <= size(app.SteerPts1.Data,2) && size(app.SteerPts1.Data,2) > 1
                app.SteerPts1.Data(:,app.ColModIdx1(2)) = [];
            end

            UpdateInputFileValuesFromGUI(app, event)
        end

        % Button pushed function: DelcolButton2
        function DelcolButton2Pushed(app, event)
            if app.ColModIdx2(2) <= size(app.SteerPts2.Data,2) && size(app.SteerPts2.Data,2) > 1
                app.SteerPts2.Data(:,app.ColModIdx2(2)) = [];
            end

            UpdateInputFileValuesFromGUI(app, event)
        end

        % Button pushed function: AddcolButton1
        function AddcolButton1Pushed(app, event)
            nCurrCol = size(app.SteerPts1.Data,2);
            if app.ColModIdx1(2) < nCurrCol
            app.SteerPts1.Data = array2table(...
            [table2array(app.SteerPts1.Data(:,1:app.ColModIdx1(2))) ...
                [0;0] ...
                table2array(app.SteerPts1.Data(:,app.ColModIdx1(2)+1:nCurrCol))]);
            elseif app.ColModIdx1(2) == nCurrCol
                app.SteerPts1.Data = array2table(...
            [table2array(app.SteerPts1.Data(:,1:app.ColModIdx1(2))) ...
                [0;0] ]);
            elseif nCurrCol == 0
                app.SteerPts1.Data = array2table([0;0]);
            end

            UpdateInputFileValuesFromGUI(app, event)
        end

        % Button pushed function: AddcolButton2
        function AddcolButton2Pushed(app, event)
            nCurrCol = size(app.SteerPts2.Data,2);
            if app.ColModIdx2(2) < nCurrCol
                app.SteerPts2.Data = array2table(...
                    [table2array(app.SteerPts2.Data(:,1:app.ColModIdx2(2))) ...
                    [0;0] ...
                    table2array(app.SteerPts2.Data(:,app.ColModIdx2(2)+1:nCurrCol))]);
            elseif app.ColModIdx2(2) == nCurrCol
                app.SteerPts2.Data = array2table(...
                    [table2array(app.SteerPts2.Data(:,1:app.ColModIdx2(2))) ...
                    [0;0] ]);
            elseif nCurrCol == 0
                app.SteerPts2.Data = array2table([0;0]);
            end

            UpdateInputFileValuesFromGUI(app, event)
        end

        % Button pushed function: SavetonewinpButton
        function SavetonewinpButtonPushed(app, event)
            BusyLamp(app,1)
            % Save dialog
            Filter = '*.m';
            Title = 'Save new input file';
            % Set the directory
            Defname = ['./Input files/' app.Inp.MoldName '/'];

            while true
                [Filename, Path] = uiputfile(Filter,Title,Defname);
                % Check if the filename is a valid MATLAB name
                if isvarname(Filename(1:end-2))
                    break
                else
                    errordlg(['Please enter a valid filename. ' ...
                        'MATLAB names must start with a letter and contain '... '
                        'only letters, numbers, or underscores'])
                    uiwait
                end
            end
            
            if Filename ~= 0
                % Save variables to specified file
                d = app.d;
                Grid = app.Grid;
                Org = app.Org;
                Ang = app.Ang;
                OrgNode = app.OrgNode;
                PreShear = app.PreShear;
                DraContr = app.DraContr;
                SteerPtsRef = app.SteerPtsRef;
                SteerPts{1,1} = app.SteerPts{1};
                SteerPts{2,1} = app.SteerPts{2};
                
                matlab.io.saveVariablesToScript([Path Filename],...
                    {'d','Grid','Org','Ang','OrgNode','PreShear','DraContr','SteerPtsRef','SteerPts'});
                
                % Read input files defined for current mold and update
                % options in Inputfile dropdown in GUI
                app.Inp.FileList = ReadInputFiles(app.Inp,'Read','course');
                app.InputfileDropDown.Items = app.Inp.FileList;
                
                fprintf('New input file successfully saved\n\n')
                
                BusyLamp(app,0)
            end
        end

        % Selection changed function: FabricdimensioninputButtonGroup
        function FabricdimensioninputButtonGroupSelectionChanged(app, event)
            if app.SizeanddButton.Value
                % Set visibility for the spinners 
                app.GridSpinner.Visible = false;
                app.GridSpinner_2.Visible = false;
                
                app.SizeSpinner.Visible = true;
                app.SizeSpinner_2.Visible = true;
                
                app.SizeSpinnerLabel.Visible = true;
                app.GridSpinnerLabel.Visible = false;
                
                % Set the values of the spinners
                app.SizeSpinner.Value = app.Size(1);
                app.SizeSpinner_2.Value = app.Size(2);
                
            elseif app.GridanddButton.Value
                % Set visibility for the spinners 
                app.GridSpinner.Visible = true;
                app.GridSpinner_2.Visible = true;
                
                app.SizeSpinner.Visible = false;
                app.SizeSpinner_2.Visible = false;
                
                app.SizeSpinnerLabel.Visible = false;
                app.GridSpinnerLabel.Visible = true;
                
                % Set the values of the spinners
                app.GridSpinner.Value = app.Grid(1);
                app.GridSpinner_2.Value = app.Grid(2);
                
            end
            
        end

        % Button pushed function: ExportSteeringCurveButton
        function ExportSteeringCurveButtonPushed(app, event)
            
            % Extract the current filename without '.m'
            CurrFilename = strsplit(app.Inp.FileName,'.');
            CurrFilename = CurrFilename{1};
            % Create dialog box with three fields
            prompt = {'Enter file name:','Enter left wheel inset [m]:','Enter right wheel inset [m]:'};
            dlgtitle = 'Steering Curve Export Settings';
            dims = [1 50];
            definput = {[CurrFilename '_steering_curve'],'0','0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            % Extract the variables for the ExportToABI function
            if ~isdeployed
                Name = ['./Output/' answer{1}];
            else
                Name = ['C:/Drape_Output/' answer{1}];
                if ~isfolder('C:/Drape_Output/')
                    mkdir('C:/Drape_Output/');
                end
            end
            WheelInset(1) = str2num(answer{2});
            WheelInset(2) = str2num(answer{3});
            % Call function (including plotting and saving of steering curve)
            ExportToABI(app.Dra,app.Grid,app.d,WheelInset,Name,app.AxHan);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RunDrapingAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunDrapingAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunDrapingAnalysis, true);
            app.RunDrapingAnalysisButton.Text = 'Run Draping Analysis';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputdataTab = uitab(app.TabGroup);
            app.InputdataTab.Title = 'Input data';
            app.MoldDropDownLabel = uilabel(app.InputdataTab);
            app.MoldDropDownLabel.Text = 'Mold';
            app.MoldDropDown = uidropdown(app.InputdataTab);
            app.MoldDropDown.Items = {'Hemisphere', 'BladeLike', 'Flat'};
            app.MoldDropDown.ItemsData = {'Hemisphere', 'BladeLike___FromMesh_5layer', 'Flat'};
            app.MoldDropDown.ValueChangedFcn = createCallbackFcn(app, @MoldDropDownValueChanged, true);
            app.MoldDropDown.Value = 'Hemisphere';
            app.InputfileLabel = uilabel(app.InputdataTab);
            app.InputfileLabel.Text = 'Inputfile';
            app.InputfileDropDown = uidropdown(app.InputdataTab);
            app.InputfileDropDown.Items = {'C_Northpole.m'};
            app.InputfileDropDown.ValueChangedFcn = createCallbackFcn(app, @InputfileDropDownValueChanged, true);
            app.InputfileDropDown.Tooltip = {''};
            app.InputfileDropDown.Value = 'C_Northpole.m';
            app.ReloadButton = uibutton(app.InputdataTab, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @InputfileDropDownValueChanged, true);
            app.ReloadButton.Text = 'Reload';
            app.DisplayproblemdataPanel = uipanel(app.InputdataTab);
            app.DisplayproblemdataPanel.Title = 'Display problem data';
            app.NumberofcellsEditFieldLabel = uilabel(app.DisplayproblemdataPanel);
            app.NumberofcellsEditFieldLabel.Text = 'Number of cells';
            app.NumberofcellsEditField = uieditfield(app.DisplayproblemdataPanel, 'numeric');
            app.NumberofcellsEditField.Editable = 'off';
            app.FabricsizeEditFieldLabel = uilabel(app.DisplayproblemdataPanel);
            app.FabricsizeEditFieldLabel.Text = 'Fabric size';
            app.FabricsizeEditField = uieditfield(app.DisplayproblemdataPanel, 'text');
            app.FabricsizeEditField.Editable = 'off';
            app.EllapsedtimeEditFieldLabel = uilabel(app.DisplayproblemdataPanel);
            app.EllapsedtimeEditFieldLabel.Text = 'Ellapsed time';
            app.EllapsedtimeEditField = uieditfield(app.DisplayproblemdataPanel, 'text');
            app.EllapsedtimeEditField.Editable = 'off';
            app.MinabsshearangEditFieldLabel = uilabel(app.DisplayproblemdataPanel);
            app.MinabsshearangEditFieldLabel.Text = 'Min abs shear ang.';
            app.MinabsshearangEditField = uieditfield(app.DisplayproblemdataPanel, 'text');
            app.InputparametersPanel = uipanel(app.InputdataTab);
            app.InputparametersPanel.Title = 'Input parameters';
            app.OrgNodePctSpinner_2 = uispinner(app.InputparametersPanel);
            app.OrgNodePctSpinner_2.Limits = [0 100];
            app.OrgNodePctSpinner_2.RoundFractionalValues = 'on';
            app.OrgNodePctSpinner_2.ValueChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.dSpinnerLabel = uilabel(app.InputparametersPanel);
            app.dSpinnerLabel.Text = 'd';
            app.dSpinner = uispinner(app.InputparametersPanel);
            app.dSpinner.Step = 0.1;
            app.dSpinner.Limits = [0 Inf];
            app.dSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.GridSpinner_2 = uispinner(app.InputparametersPanel);
            app.GridSpinner_2.ValueChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.GridSpinner_2.Visible = 'off';
            app.GridSpinnerLabel = uilabel(app.InputparametersPanel);
            app.GridSpinnerLabel.Visible = 'off';
            app.GridSpinnerLabel.Text = 'Grid';
            app.GridSpinner = uispinner(app.InputparametersPanel);
            app.GridSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.GridSpinner.Visible = 'off';
            app.OrgSpinner_2 = uispinner(app.InputparametersPanel);
            app.OrgSpinner_2.ValueChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.OrgSpinnerLabel = uilabel(app.InputparametersPanel);
            app.OrgSpinnerLabel.Text = 'Org';
            app.OrgSpinner = uispinner(app.InputparametersPanel);
            app.OrgSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.OrgNodeSpinner_2 = uispinner(app.InputparametersPanel);
            app.OrgNodeSpinner_2.Limits = [1 Inf];
            app.OrgNodeSpinner_2.ValueChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.OrgNodeSpinner_2.Visible = 'off';
            app.OrgNodeSpinner_2.Value = 1;
            app.OrgNodeSpinnerLabel = uilabel(app.InputparametersPanel);
            app.OrgNodeSpinnerLabel.Visible = 'off';
            app.OrgNodeSpinnerLabel.Text = 'OrgNode';
            app.OrgNodeSpinner = uispinner(app.InputparametersPanel);
            app.OrgNodeSpinner.Limits = [1 Inf];
            app.OrgNodeSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.OrgNodeSpinner.Visible = 'off';
            app.OrgNodeSpinner.Value = 1;
            app.AngSpinnerLabel = uilabel(app.InputparametersPanel);
            app.AngSpinnerLabel.Text = 'Ang';
            app.AngSpinner = uispinner(app.InputparametersPanel);
            app.AngSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.PreShearSpinnerLabel = uilabel(app.InputparametersPanel);
            app.PreShearSpinnerLabel.Text = 'PreShear';
            app.PreShearSpinner = uispinner(app.InputparametersPanel);
            app.PreShearSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.OrgNodePctSpinnerLabel = uilabel(app.InputparametersPanel);
            app.OrgNodePctSpinnerLabel.Text = 'OrgNodePct';
            app.OrgNodePctSpinner = uispinner(app.InputparametersPanel);
            app.OrgNodePctSpinner.Limits = [0 100];
            app.OrgNodePctSpinner.RoundFractionalValues = 'on';
            app.OrgNodePctSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.SizeSpinner_2 = uispinner(app.InputparametersPanel);
            app.SizeSpinner_2.Step = 0.1;
            app.SizeSpinner_2.Limits = [0 Inf];
            app.SizeSpinner_2.ValueChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.SizeSpinnerLabel = uilabel(app.InputparametersPanel);
            app.SizeSpinnerLabel.Text = 'Size';
            app.SizeSpinner = uispinner(app.InputparametersPanel);
            app.SizeSpinner.Step = 0.1;
            app.SizeSpinner.Limits = [0 Inf];
            app.SizeSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.DrapingcontrolPanel = uipanel(app.InputdataTab);
            app.DrapingcontrolPanel.Title = 'Draping control';
            app.TransverseButtonGroup = uibuttongroup(app.DrapingcontrolPanel);
            app.TransverseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.TransverseButtonGroup.Title = 'Transverse';
            app.SteeringButton_trans = uitogglebutton(app.TransverseButtonGroup);
            app.SteeringButton_trans.Text = 'Steering';
            app.GeodesicButton_trans = uitogglebutton(app.TransverseButtonGroup);
            app.GeodesicButton_trans.Text = 'Geodesic';
            app.GeodesicButton_trans.Value = true;
            app.SteerPts2 = uitable(app.DrapingcontrolPanel);
            app.SteerPts2.ColumnName = {''};
            app.SteerPts2.RowName = {'x'; 'y'};
            app.SteerPts2.ColumnEditable = true;
            app.SteerPts2.CellEditCallback = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.SteerPts2.CellSelectionCallback = createCallbackFcn(app, @SteerPts2CellSelection, true);
            app.TransverseptrefDropDownLabel = uilabel(app.DrapingcontrolPanel);
            app.TransverseptrefDropDownLabel.Text = 'Transverse pt. ref';
            app.TransverseptrefDropDown = uidropdown(app.DrapingcontrolPanel);
            app.TransverseptrefDropDown.Items = {'Abs'};
            app.TransverseptrefDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.TransverseptrefDropDown.Value = 'Abs';
            app.LongitudinalButtonGroup = uibuttongroup(app.DrapingcontrolPanel);
            app.LongitudinalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.LongitudinalButtonGroup.Title = 'Longitudinal';
            app.SteeringButton_long = uitogglebutton(app.LongitudinalButtonGroup);
            app.SteeringButton_long.Text = 'Steering';
            app.GeodesicButton_long = uitogglebutton(app.LongitudinalButtonGroup);
            app.GeodesicButton_long.Text = 'Geodesic';
            app.GeodesicButton_long.Value = true;
            app.SteerPts1 = uitable(app.DrapingcontrolPanel);
            app.SteerPts1.ColumnName = {''};
            app.SteerPts1.RowName = {'x'; 'y'};
            app.SteerPts1.ColumnEditable = true;
            app.SteerPts1.CellEditCallback = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.SteerPts1.CellSelectionCallback = createCallbackFcn(app, @SteerPts1CellSelection, true);
            app.LongitudinalptrefDropDownLabel = uilabel(app.DrapingcontrolPanel);
            app.LongitudinalptrefDropDownLabel.Text = 'Longitudinal pt. ref';
            app.LongitudinalptrefDropDown = uidropdown(app.DrapingcontrolPanel);
            app.LongitudinalptrefDropDown.Items = {'Abs'};
            app.LongitudinalptrefDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateInputFileValuesFromGUI, true);
            app.LongitudinalptrefDropDown.Value = 'Abs';
            app.AddcolButton1 = uibutton(app.DrapingcontrolPanel, 'push');
            app.AddcolButton1.ButtonPushedFcn = createCallbackFcn(app, @AddcolButton1Pushed, true);
            app.AddcolButton1.Enable = 'off';
            app.AddcolButton1.Text = 'Add col';
            app.DelcolButton1 = uibutton(app.DrapingcontrolPanel, 'push');
            app.DelcolButton1.ButtonPushedFcn = createCallbackFcn(app, @DelcolButton1Pushed, true);
            app.DelcolButton1.Enable = 'off';
            app.DelcolButton1.Text = 'Del. col';
            app.AddcolButton2 = uibutton(app.DrapingcontrolPanel, 'push');
            app.AddcolButton2.ButtonPushedFcn = createCallbackFcn(app, @AddcolButton2Pushed, true);
            app.AddcolButton2.Enable = 'off';
            app.AddcolButton2.Text = 'Add col';
            app.DelcolButton2 = uibutton(app.DrapingcontrolPanel, 'push');
            app.DelcolButton2.ButtonPushedFcn = createCallbackFcn(app, @DelcolButton2Pushed, true);
            app.DelcolButton2.Enable = 'off';
            app.DelcolButton2.Text = 'Del. col';
            app.SavetonewinpButton = uibutton(app.InputdataTab, 'push');
            app.SavetonewinpButton.ButtonPushedFcn = createCallbackFcn(app, @SavetonewinpButtonPushed, true);
            app.SavetonewinpButton.Text = 'Save to new inp';
            app.PlotsettingsTab = uitab(app.TabGroup);
            app.PlotsettingsTab.Title = 'Plot settings';
            app.ShearanglesButtonGroup = uibuttongroup(app.PlotsettingsTab);
            app.ShearanglesButtonGroup.Title = 'Shear angles';
            app.AbsoluteButton = uiradiobutton(app.ShearanglesButtonGroup);
            app.AbsoluteButton.Text = 'Absolute';
            app.SignedButton = uiradiobutton(app.ShearanglesButtonGroup);
            app.SignedButton.Text = 'Signed';
            app.SignedButton.Value = true;
            app.UpdateplotButton = uibutton(app.PlotsettingsTab, 'push');
            app.UpdateplotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlot, true);
            app.UpdateplotButton.Enable = 'off';
            app.UpdateplotButton.Text = 'Update plot';
            app.ColormapButtonGroup = uibuttongroup(app.PlotsettingsTab);
            app.ColormapButtonGroup.Title = 'Color map';
            app.JetButton = uiradiobutton(app.ColormapButtonGroup);
            app.JetButton.Text = 'Jet';
            app.JetButton.Value = true;
            app.AbaqusButton = uiradiobutton(app.ColormapButtonGroup);
            app.AbaqusButton.Text = 'Abaqus';
            app.AbaqusshearlimitSpinnerLabel = uilabel(app.ColormapButtonGroup);
            app.AbaqusshearlimitSpinnerLabel.Text = 'Abaqus shear limit';
            app.AbaqusshearlimitSpinner = uispinner(app.ColormapButtonGroup);
            app.AbaqusshearlimitSpinner.Limits = [0 Inf];
            app.AbaqusshearlimitSpinner.Value = 10;
            app.PlotmoldtrimeshCheckBox = uicheckbox(app.PlotsettingsTab);
            app.PlotmoldtrimeshCheckBox.Text = 'Plot mold tri mesh';
            app.PlotmoldedgesCheckBox = uicheckbox(app.PlotsettingsTab);
            app.PlotmoldedgesCheckBox.Text = 'Plot mold edges';
            app.PlotmoldedgesCheckBox.Value = true;
            app.PlotgeneratorcurvesCheckBox = uicheckbox(app.PlotsettingsTab);
            app.PlotgeneratorcurvesCheckBox.Text = 'Plot generator curves';
            app.PlotgeneratorcurvesCheckBox.Value = true;
            app.FiguresizeDropDownLabel = uilabel(app.PlotsettingsTab);
            app.FiguresizeDropDownLabel.Text = 'Figure size';
            app.FiguresizeDropDown = uidropdown(app.PlotsettingsTab);
            app.FiguresizeDropDown.Items = {'Normal', 'Full', 'Half', 'Full other'};
            app.FiguresizeDropDown.ItemsData = {'normal', 'full', 'half', 'full-other'};
            app.FiguresizeDropDown.Value = 'normal';
            app.PlotmoldmeshboundaryCheckBox = uicheckbox(app.PlotsettingsTab);
            app.PlotmoldmeshboundaryCheckBox.Text = 'Plot mold mesh boundary';
            app.PlotmoldmeshboundaryCheckBox.Value = true;
            app.CalibrateotherscreenButton = uibutton(app.PlotsettingsTab, 'push');
            app.CalibrateotherscreenButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateotherscreenButtonPushed, true);
            app.CalibrateotherscreenButton.Text = 'Calibrate other screen';
            app.DisplaylegendCheckBox = uicheckbox(app.PlotsettingsTab);
            app.DisplaylegendCheckBox.Text = 'Display legend';
            app.DisplaylegendCheckBox.Value = true;
            app.PlyplotzoffsetSpinnerLabel = uilabel(app.PlotsettingsTab);
            app.PlyplotzoffsetSpinnerLabel.Text = 'Ply plot z-offset';
            app.PlyplotzoffsetSpinner = uispinner(app.PlotsettingsTab);
            app.PlyplotzoffsetSpinner.Value = 1;
            app.mmLabel = uilabel(app.PlotsettingsTab);
            app.mmLabel.Text = 'mm';
            app.PlycontourcolorDropDownLabel = uilabel(app.PlotsettingsTab);
            app.PlycontourcolorDropDownLabel.Text = 'Ply contour color';
            app.PlycontourcolorDropDown = uidropdown(app.PlotsettingsTab);
            app.PlycontourcolorDropDown.Items = {'Shear', 'Fiber orientation dev.', 'Flat'};
            app.PlycontourcolorDropDown.ItemsData = {'Shear', 'FibDev', 'Flat'};
            app.PlycontourcolorDropDown.Value = 'Shear';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.OrgNodeinputButtonGroup = uibuttongroup(app.SettingsTab);
            app.OrgNodeinputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OrgNodeinputButtonGroupSelectionChanged, true);
            app.OrgNodeinputButtonGroup.Title = 'OrgNode input';
            app.NodeindicesButton = uitogglebutton(app.OrgNodeinputButtonGroup);
            app.NodeindicesButton.Text = 'Node indices';
            app.NodeindicesButton.Value = true;
            app.GriddimpctButton = uitogglebutton(app.OrgNodeinputButtonGroup);
            app.GriddimpctButton.Text = 'Grid dim. pct';
            app.keyboardafterdrapinganalysisCheckBox = uicheckbox(app.SettingsTab);
            app.keyboardafterdrapinganalysisCheckBox.Text = '"keyboard" after draping analysis';
            app.FabricdimensioninputButtonGroup = uibuttongroup(app.SettingsTab);
            app.FabricdimensioninputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FabricdimensioninputButtonGroupSelectionChanged, true);
            app.FabricdimensioninputButtonGroup.Title = 'Fabric dimension input';
            app.SizeanddButton = uitogglebutton(app.FabricdimensioninputButtonGroup);
            app.SizeanddButton.Text = 'Size and d';
            app.GridanddButton = uitogglebutton(app.FabricdimensioninputButtonGroup);
            app.GridanddButton.Text = 'Grid and d';
            app.GridanddButton.Value = true;
            app.TrimplytonetboundaryCheckBox = uicheckbox(app.SettingsTab);
            app.TrimplytonetboundaryCheckBox.Text = ' Trim ply to net boundary';
            app.TrimplytonetboundaryCheckBox.Value = true;
            app.UserguideTab = uitab(app.TabGroup);
            app.UserguideTab.Title = 'User guide';
            app.UserGuideText = uitextarea(app.UserguideTab);
            app.UserGuideText.Editable = 'off';
            app.Restore3DviewButton = uibutton(app.UIFigure, 'push');
            app.Restore3DviewButton.ButtonPushedFcn = createCallbackFcn(app, @Restore3DviewButtonPushed, true);
            app.Restore3DviewButton.Enable = 'off';
            app.Restore3DviewButton.Text = 'Restore 3D view';
            app.DtopviewButton = uibutton(app.UIFigure, 'push');
            app.DtopviewButton.ButtonPushedFcn = createCallbackFcn(app, @DtopviewButtonPushed, true);
            app.DtopviewButton.Enable = 'off';
            app.DtopviewButton.Text = '2D top view';
            app.AxisequalButton = uibutton(app.UIFigure, 'state');
            app.AxisequalButton.ValueChangedFcn = createCallbackFcn(app, @AxisequalButtonValueChanged, true);
            app.AxisequalButton.Enable = 'off';
            app.AxisequalButton.Text = 'Axis equal';
            app.AxisequalButton.Value = true;
            app.PlotmoldButton = uibutton(app.UIFigure, 'push');
            app.PlotmoldButton.ButtonPushedFcn = createCallbackFcn(app, @PlotmoldButtonPushed, true);
            app.PlotmoldButton.Text = 'Plot mold';
            app.KinDrapeMAXsinglecourseappLabel = uilabel(app.UIFigure);
            app.KinDrapeMAXsinglecourseappLabel.Text = 'KinDrapeMAX single course app';
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.StateLamp = uilamp(app.UIFigure);
            app.ExportSteeringCurveButton = uibutton(app.UIFigure, 'push');
            app.ExportSteeringCurveButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSteeringCurveButtonPushed, true);
            app.ExportSteeringCurveButton.Enable = 'off';
            app.ExportSteeringCurveButton.Text = 'Export Steering Curve';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DrapeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end